classdef dataviz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TimeSliderLabel             matlab.ui.control.Label
        TimeSlider                  matlab.ui.control.Slider
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        DisplayIntensityProfileCheckBox  matlab.ui.control.CheckBox
        XSliderLabel                matlab.ui.control.Label
        XSlider                     matlab.ui.control.Slider
        YSliderLabel                matlab.ui.control.Label
        YSlider                     matlab.ui.control.Slider
        FlipIntensityProfileButton  matlab.ui.control.StateButton
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, kwave_struct)
            fields = fieldnames(kwave_struct);
            for i = 1:length(fields)
                if strcmp('kgrid',fields{i})
                    app.kgrid = kwave_struct.kgrid;
                else
                    app.data = kwave_struct.(fields{i});
                    app.current_params = fieldname_to_param(fields{i});
                end
            end
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            plot_kwave(app);
        end

        % Value changed function: FlipIntensityProfileButton
        function FlipIntensityProfileButtonValueChanged(app, event)
            plot_kwave(app);
        end

        % Value changed function: DisplayIntensityProfileCheckBox
        function DisplayIntensityProfileCheckBoxValueChanged(app, event)
            plot_kwave(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'xaxis')
            ylabel(app.UIAxes, 'yaxis')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'xaxis')
            ylabel(app.UIAxes_2, 'yaxis')
            app.DisplayIntensityProfileCheckBox = uicheckbox(app.UIFigure);
            app.DisplayIntensityProfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayIntensityProfileCheckBoxValueChanged, true);
            app.DisplayIntensityProfileCheckBox.Text = 'Display Intensity Profile';
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.FlipIntensityProfileButton = uibutton(app.UIFigure, 'state');
            app.FlipIntensityProfileButton.ValueChangedFcn = createCallbackFcn(app, @FlipIntensityProfileButtonValueChanged, true);
            app.FlipIntensityProfileButton.Text = 'Flip Intensity Profile';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dataviz(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end